classdef app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        TabGroup                        matlab.ui.container.TabGroup
        equationSolution                matlab.ui.container.Tab
        UIAxes                          matlab.ui.control.UIAxes
        KhongphnlyEditFieldLabel        matlab.ui.control.Label
        startField                      matlab.ui.control.NumericEditField
        endField                        matlab.ui.control.NumericEditField
        SaisEditFieldLabel              matlab.ui.control.Label
        errorField                      matlab.ui.control.NumericEditField
        method                          matlab.ui.container.ButtonGroup
        divisioinRatio                  matlab.ui.control.RadioButton
        iterationRatio                  matlab.ui.control.RadioButton
        newtonRatio                     matlab.ui.control.RadioButton
        dayCungRatio                    matlab.ui.control.RadioButton
        KtqunghimEditFieldLabel         matlab.ui.control.Label
        result                          matlab.ui.control.NumericEditField
        SlnlpEditFieldLabel             matlab.ui.control.Label
        iteration                       matlab.ui.control.NumericEditField
        summit                          matlab.ui.control.Button
        NhpphngtrnhEditFieldLabel       matlab.ui.control.Label
        equationField                   matlab.ui.control.EditField
        NisuyTab                        matlab.ui.container.Tab
        UIAxes_Interpolation            matlab.ui.control.UIAxes
        Compute                         matlab.ui.control.Button
        ErrorsHandle                    matlab.ui.control.EditField
        ErrorsHandleResult              matlab.ui.control.EditField
        NhpccgitrxcchnhaubikhongtrngLabel_3  matlab.ui.control.Label
        xArray                          matlab.ui.control.EditField
        NhpccgitrycchnhaubikhongtrngLabel_3  matlab.ui.control.Label
        yArray                          matlab.ui.control.EditField
        ChnphngphpnisuyLabel            matlab.ui.control.Label
        technique                       matlab.ui.control.DropDown
        athcnisuyEditFieldLabel         matlab.ui.control.Label
        interpolationPolynomial         matlab.ui.control.EditField
        KtqunisuyEditFieldLabel         matlab.ui.control.Label
        interpolationResult             matlab.ui.control.EditField
        NhpgitrcnnisuyEditFieldLabel    matlab.ui.control.Label
        interpolationNumber             matlab.ui.control.NumericEditField
        HiquyTab                        matlab.ui.container.Tab
        hoiquyUIAxes                    matlab.ui.control.UIAxes
        hoiQuyMethodButtonGroup         matlab.ui.container.ButtonGroup
        regressionOptionButton          matlab.ui.control.RadioButton
        exponentialOptionButton         matlab.ui.control.RadioButton
        logaritOptionButton             matlab.ui.control.RadioButton
        NhpdliuxEditField_3Label_2      matlab.ui.control.Label
        hoiQuyDuLieuYField              matlab.ui.control.EditField
        NhpdliuyEditField_3Label_2      matlab.ui.control.Label
        hoiQuyDuLieuXField              matlab.ui.control.EditField
        NhpgitrcndonEditFieldLabel      matlab.ui.control.Label
        hoiQuyPredictorEditField        matlab.ui.control.NumericEditField
        hoiQuyPredictionButton          matlab.ui.control.Button
        hoiQuyPredictionResultTitle     matlab.ui.control.Label
        hoiQuyPredictionResultLabel     matlab.ui.control.Label
        hoiQuyDrawerButton              matlab.ui.control.Button
        phuongTrinhHoiQuyTitle          matlab.ui.control.Label
        phuongTrinhHoiQuyLabel          matlab.ui.control.Label
        hoiQuyErrorMessage              matlab.ui.control.Label
        ohmTab                          matlab.ui.container.Tab
        ButtonGroup_2                   matlab.ui.container.ButtonGroup
        HocLabel_3                      matlab.ui.control.Label
        NhpccgitrxcchnhaubikhongtrngLabel_2  matlab.ui.control.Label
        xArray_2                        matlab.ui.control.EditField
        NhpccgitrycchnhaubikhongtrngLabel_2  matlab.ui.control.Label
        yArray_2                        matlab.ui.control.EditField
        NhphmsEditFieldLabel_3          matlab.ui.control.Label
        expressionInput_2               matlab.ui.control.EditField
        NhpbchEditFieldLabel_2          matlab.ui.control.Label
        hInput_2                        matlab.ui.control.NumericEditField
        ChnbcsaisDropDownLabel_2        matlab.ui.control.Label
        errorOh_2                       matlab.ui.control.DropDown
        Computexy_2                     matlab.ui.control.Button
        Computef_2                      matlab.ui.control.Button
        ErrorsHandle_2                  matlab.ui.control.EditField
        derivativeErrHandle_2           matlab.ui.control.EditField
        ChnphngphptnhohmListBoxLabel_2  matlab.ui.control.Label
        methodCalc                      matlab.ui.control.ListBox
        NhpgitrcntnhohmEditFieldLabel_2  matlab.ui.control.Label
        derivativeValue                 matlab.ui.control.NumericEditField
        KtquEditFieldLabel_3            matlab.ui.control.Label
        derivativeResult                matlab.ui.control.NumericEditField
        TchphnTab                       matlab.ui.container.Tab
        NhpdliuxEditField_3Label        matlab.ui.control.Label
        dulieuXField                    matlab.ui.control.EditField
        NhpdliuyEditField_3Label        matlab.ui.control.Label
        dulieuYField                    matlab.ui.control.EditField
        HocLabel                        matlab.ui.control.Label
        NhphmsEditFieldLabel            matlab.ui.control.Label
        hamTichPhanField                matlab.ui.control.EditField
        tichPhanOptionsButtonGroup      matlab.ui.container.ButtonGroup
        HnhthangButton                  matlab.ui.control.RadioButton
        Simpson13Button                 matlab.ui.control.RadioButton
        Simpson38Button                 matlab.ui.control.RadioButton
        NhpNEditFieldLabel              matlab.ui.control.Label
        nField                          matlab.ui.control.NumericEditField
        KtquEditFieldLabel              matlab.ui.control.Label
        resultTichPhanField             matlab.ui.control.NumericEditField
        solveTichPhanButton             matlab.ui.control.Button
        NhpcntnhTchphnLabel             matlab.ui.control.Label
        aEditFieldLabel                 matlab.ui.control.Label
        canTichPhanAField               matlab.ui.control.NumericEditField
        bEditFieldLabel                 matlab.ui.control.Label
        canTichPhanBField               matlab.ui.control.NumericEditField
        tichphanErrorLabel              matlab.ui.control.Label
        GiithiunhmTab                   matlab.ui.container.Tab
        derivativeErrHandle             matlab.ui.control.EditField
    end


    methods (Access = private)

        % Callback function
        function equationFieldValueChanging(app, event)
            
        end

        % Button pushed function: summit
        function summitButtonPushed(app, event)
    
        end

        % Callback function
        function startFieldValueChanged(app, event)
            
        end

        % Selection changed function: method
        function methodSelectionChanged(app, event)

        end

        % Button pushed function: solveTichPhanButton
        function solveTichPhanButtonPushed(app, event)
            try
                x = app.dulieuXField.Value;
                y = app.dulieuYField.Value;

                if (app.hamTichPhanField.Value) 
                    fx = app.hamTichPhanField.Value;
                    fx = str2func(['@(x)' fx]);
                else
                    x = str2num(x);
                    y = str2num(y);
                    fx = Lagrange(x, y);
                end
                
                a = app.canTichPhanAField.Value;
                b = app.canTichPhanBField.Value;
                
                n = app.nField.Value;
    
                option = app.tichPhanOptionsButtonGroup.SelectedObject.Value;
    
                kq = 0;
                disp(option);
                if (option == app.HnhthangButton.Value) 
                    kq = tichphanhinhthang(fx, a, b, n);  
                elseif (option == app.Simpson13Button.Value)
                    kq = tichphanSimpson13(fx, a, b, n);
                elseif (option == app.Simpson38Button.Value)
                    kq = tichphanSimpson38(fx, a, b, n);
                end
                  
                app.resultTichPhanField.Value = kq;
                app.tichphanErrorLabel.Text = '';
            catch exception
                app.tichphanErrorLabel.Text = exception.message;
                disp(exception);
            end
            
        end

        % Button pushed function: hoiQuyDrawerButton
        function hoiQuyDrawerButtonPushed(app, event)
            try
                a = app.hoiQuyDuLieuXField.Value;
                a = str2num(a);
                b = app.hoiQuyDuLieuYField.Value;
                b = str2num(b);
                disp(a, b);
                
                option = app.hoiQuyMethodButtonGroup.SelectedObject.Value;
                fx = 0;
                if (option == app.regressionOptionButton.Value)
                    fx = linearRegression(a, b);
                elseif (option == app.exponentialOptionButton.Value)
                    fx = exponentialRegression(a, b);
                elseif (option == app.logaritOptionButton.Value) 
                    fx = logarithmicRegression(a, b);
                end

                n = min(a) : 0.1 : max(a);
                plot(app.hoiquyUIAxes, n, fx(n), 'b', 'LineWidth', 1);
    %             scatter(app.hoiquyUIAxes, a, b, 'r', 'LineWidth', 1);
    
                s = func2str(fx);
                app.phuongTrinhHoiQuyLabel.Text = s(5:end);
                app.hoiQuyErrorMessage.Text = '';
            catch e
                app.hoiQuyErrorMessage.Text = e.message;
                disp(e);
            end
        end

        % Button pushed function: hoiQuyPredictionButton
        function hoiQuyPredictionButtonPushed(app, event)
            try
                a = app.hoiQuyDuLieuXField.Value;
                a = str2num(a);
                b = app.hoiQuyDuLieuYField.Value;
                b = str2num(b);
                
                option = app.hoiQuyMethodButtonGroup.SelectedObject.Value;
                fx = 0;
                if (option == app.regressionOptionButton.Value)
                    fx = linearRegression(a, b);
                elseif (option == app.exponentialOptionButton.Value)
                    fx = exponentialRegression(a, b);
                elseif (option == app.logaritOptionButton.Value) 
                    fx = logarithmicRegression(a, b);
                end
                
                x = app.hoiQuyPredictorEditField.Value;
                app.hoiQuyPredictionResultLabel.Text = num2str(fx(x));
                app.hoiQuyErrorMessage.Text = '';
            catch e
                app.hoiQuyErrorMessage.Text = e.message;
                disp(e);
            end
        end

        % Button pushed function: Compute
        function ComputeButtonPushed(app, event)
            x = str2num(app.xArray.Value);
            y = str2num(app.yArray.Value);
            app.interpolationResult.Value = " ";
            app.interpolationPolynomial.Value = " ";
            if (length(x) == length(y))
                app.ErrorsHandle.Visible = 'off';
                if (app.technique.Value == "Lagrange")
                    f = Lagrange(x,y);
                else (app.technique.Value == "Newton")
                    f = NewtonInterpolation(x,y);
                end
                if (f == 0)
                    app.interpolationPolynomial.Value = "0";
                else
                app.interpolationPolynomial.Value = string(simplify(f));
                end
                fplot(app.UIAxes_Interpolation,f);
                grid(app.UIAxes_Interpolation, 'on');
                axis(app.UIAxes_Interpolation, [-100 100 -100 100]);
                value = app.interpolationNumber.Value;
                f = app.interpolationPolynomial.Value;
                f = str2func(['@(x)' char(f)]);
                app.interpolationResult.Value = string(f(value));
            else
                app.ErrorsHandle.Visible = 'on';
                app.ErrorsHandle.Value = "Oops, make sure the length of x and y is equal!!!";
            end
        end

        % Value changed function: interpolationNumber
        function interpolationNumberValueChanged(app, event)
            value = app.interpolationNumber.Value;
            f = app.interpolationPolynomial.Value;
            f = str2func(['@(x)' char(f)]);
            app.interpolationResult.Value = string(f(value));
        end

        % Button pushed function: Computexy_2
        function Computexy_2ButtonPushed(app, event)
            app.derivativeErrHandle.Visible = 'off';
            app.ErrorsHandle_2.Visible = 'off';
            idx = 0;
            value = app.derivativeValue.Value;
            result = app.derivativeResult.Value;
            x = str2num(app.xArray_2.Value);
            y = str2num(app.yArray_2.Value); 
            for i = 1:length(x)
                if x(i) == value
                    idx = i;
                end
            end
            if length(x) == length(y)
                if idx == 0
                    app.derivativeErrHandle_2.Visible = 'on';
                    app.derivativeErrHandle_2.Value = "Oops, look like something wrong. Please try again!!";
                elseif idx ~= 0
                    if (app.methodCalc.Value == "xấp xỉ tiến")
                        if idx == length(x)
                            app.derivativeErrHandle_2.Visible = 'on';
                            app.derivativeErrHandle_2.Value = "Oops, look like something wrong. Please try again!!"; 
                        else
                        result = (y(idx + 1) - y(idx))/(x(idx + 1) - x(idx));
                        end
                    elseif (app.methodCalc.Value == "xấp xỉ lùi")
                        if idx == 1
                            app.derivativeErrHandle_2.Visible = 'on';
                            app.derivativeErrHandle_2.Value = "Oops, look like something wrong. Please try again!!"; 
                        else
                        result = (y(idx) - y(idx - 1))/(x(idx) - x(idx - 1));
                        end
                    elseif (app.methodCalc.Value == "trung tâm")
                        if idx == 1 || idx == length(x)
                            app.derivativeErrHandle_2.Visible = 'on';
                            app.derivativeErrHandle_2.Value = "Oops, look like something wrong. Please try again!!"; 
                        else
                        result = (y(idx + 1) - y(idx - 1))/(x(idx + 1) - x(idx - 1));
                        end
                    end
                end
            elseif length(x) ~= length(y)
                app.ErrorsHandle_2.Visible = 'on';
                app.ErrorsHandle_2.Value = "Oops, make sure the length of x and y is equal!!!";
            end
            app.derivativeResult.Value = result;
        end

        % Button pushed function: Computef_2
        function Computef_2ButtonPushed(app, event)
            app.derivativeErrHandle.Visible = 'off';
            value = app.derivativeValue.Value;
            result = app.derivativeResult.Value; 
            f = app.expressionInput.Value;
            f = str2func(['@(x)' f]);
            h = app.hInput.Value;
            if h ~= 0
                if (app.methodCalc.Value == "xấp xỉ tiến")
                    if (app.errorOh.Value == "O(h)")
                        result = (f(value + h) - f(value))/h;
                    elseif (app.errorOh.Value == "O(h^2)")
                        result = (-3*f(value) + 4*f(value + h) - f(value + 2*h))/(2*h);
                    end
                elseif (app.methodCalc.Value == "xấp xỉ lùi")
                    if (app.errorOh.Value == "O(h)")
                        result = (f(value) - f(value - h))/h;
                    elseif (app.errorOh.Value == "O(h^2)")
                        result = (3*f(value) - 4*f(value - h) + f(value - 2*h))/(2*h);
                    end
                elseif (app.methodCalc.Value == "trung tâm")
                    if (app.errorOh.Value == "O(h)")
                        result = (f(value + h) + f(value) - f(value - h))/(2*h);
                    elseif (app.errorOh.Value == "O(h^2)")
                        result = (f(value + h) + f(value) - f(value - h))/(2*h);
                    end 
                end
            else
                app.derivativeErrHandle.Visible = 'on';
                app.derivativeErrHandle.Value = "Oops, look like something wrong. Please try again!!";
            end       
            app.derivativeResult.Value = result;

        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.equationSolution = uitab(app.TabGroup);
            app.equationSolution.Title = 'Nghiệm';
            app.UIAxes = uiaxes(app.equationSolution);
            title(app.UIAxes, 'Đồ thị hàm số')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.KhongphnlyEditFieldLabel = uilabel(app.equationSolution);
            app.KhongphnlyEditFieldLabel.Text = 'Khoảng phân ly';
            app.startField = uieditfield(app.equationSolution, 'numeric');
            app.endField = uieditfield(app.equationSolution, 'numeric');
            app.SaisEditFieldLabel = uilabel(app.equationSolution);
            app.SaisEditFieldLabel.Text = 'Sai số';
            app.errorField = uieditfield(app.equationSolution, 'numeric');
            app.method = uibuttongroup(app.equationSolution);
            app.method.SelectionChangedFcn = createCallbackFcn(app, @methodSelectionChanged, true);
            app.method.Title = 'Chọn phương pháp tìm';
            app.divisioinRatio = uiradiobutton(app.method);
            app.divisioinRatio.Text = 'Chia đôi';
            app.divisioinRatio.Value = true;
            app.iterationRatio = uiradiobutton(app.method);
            app.iterationRatio.Text = 'Lặp';
            app.newtonRatio = uiradiobutton(app.method);
            app.newtonRatio.Text = 'Newton';
            app.dayCungRatio = uiradiobutton(app.method);
            app.dayCungRatio.Text = 'Dây cung';
            app.KtqunghimEditFieldLabel = uilabel(app.equationSolution);
            app.KtqunghimEditFieldLabel.Text = 'Kết quả nghiệm';
            app.result = uieditfield(app.equationSolution, 'numeric');
            app.SlnlpEditFieldLabel = uilabel(app.equationSolution);
            app.SlnlpEditFieldLabel.Text = 'Số lần lặp';
            app.iteration = uieditfield(app.equationSolution, 'numeric');
            app.summit = uibutton(app.equationSolution, 'push');
            app.summit.ButtonPushedFcn = createCallbackFcn(app, @summitButtonPushed, true);
            app.summit.Text = 'Tính';
            app.NhpphngtrnhEditFieldLabel = uilabel(app.equationSolution);
            app.NhpphngtrnhEditFieldLabel.Text = 'Nhập phương trình';
            app.equationField = uieditfield(app.equationSolution, 'text');
            app.NisuyTab = uitab(app.TabGroup);
            app.NisuyTab.Title = 'Nội suy';
            app.UIAxes_Interpolation = uiaxes(app.NisuyTab);
            title(app.UIAxes_Interpolation, 'Đồ thị')
            xlabel(app.UIAxes_Interpolation, 'X')
            ylabel(app.UIAxes_Interpolation, 'Y')
            app.Compute = uibutton(app.NisuyTab, 'push');
            app.Compute.ButtonPushedFcn = createCallbackFcn(app, @ComputeButtonPushed, true);
            app.Compute.Text = 'Tính toán';
            app.ErrorsHandle = uieditfield(app.NisuyTab, 'text');
            app.ErrorsHandle.Editable = 'off';
            app.ErrorsHandle.Visible = 'off';
            app.ErrorsHandleResult = uieditfield(app.NisuyTab, 'text');
            app.ErrorsHandleResult.Visible = 'off';
            app.NhpccgitrxcchnhaubikhongtrngLabel_3 = uilabel(app.NisuyTab);
            app.NhpccgitrxcchnhaubikhongtrngLabel_3.Text = 'Nhập các giá trị x (cách nhau bởi khoảng trắng) :';
            app.xArray = uieditfield(app.NisuyTab, 'text');
            app.NhpccgitrycchnhaubikhongtrngLabel_3 = uilabel(app.NisuyTab);
            app.NhpccgitrycchnhaubikhongtrngLabel_3.Text = 'Nhập các giá trị y (cách nhau bởi khoảng trắng) :';
            app.yArray = uieditfield(app.NisuyTab, 'text');
            app.ChnphngphpnisuyLabel = uilabel(app.NisuyTab);
            app.ChnphngphpnisuyLabel.Text = 'Chọn phương pháp nội suy :';
            app.technique = uidropdown(app.NisuyTab);
            app.technique.Items = {'Lagrange', 'Newton    ', ''};
            app.technique.Value = 'Newton    ';
            app.athcnisuyEditFieldLabel = uilabel(app.NisuyTab);
            app.athcnisuyEditFieldLabel.Text = 'Đa thức nội suy';
            app.interpolationPolynomial = uieditfield(app.NisuyTab, 'text');
            app.KtqunisuyEditFieldLabel = uilabel(app.NisuyTab);
            app.KtqunisuyEditFieldLabel.Text = 'Kết quả nội suy';
            app.interpolationResult = uieditfield(app.NisuyTab, 'text');
            app.NhpgitrcnnisuyEditFieldLabel = uilabel(app.NisuyTab);
            app.NhpgitrcnnisuyEditFieldLabel.Text = 'Nhập giá trị cần nội suy';
            app.interpolationNumber = uieditfield(app.NisuyTab, 'numeric');
            app.interpolationNumber.ValueChangedFcn = createCallbackFcn(app, @interpolationNumberValueChanged, true);
            app.HiquyTab = uitab(app.TabGroup);
            app.HiquyTab.Title = 'Hồi quy';
            app.hoiquyUIAxes = uiaxes(app.HiquyTab);
            title(app.hoiquyUIAxes, 'Đồ thị')
            xlabel(app.hoiquyUIAxes, 'X')
            ylabel(app.hoiquyUIAxes, 'Y')
            zlabel(app.hoiquyUIAxes, 'Z')
            app.hoiQuyMethodButtonGroup = uibuttongroup(app.HiquyTab);
            app.hoiQuyMethodButtonGroup.Title = 'Chọn phương pháp';
            app.regressionOptionButton = uiradiobutton(app.hoiQuyMethodButtonGroup);
            app.regressionOptionButton.Text = 'Tuyến tính';
            app.regressionOptionButton.Value = true;
            app.exponentialOptionButton = uiradiobutton(app.hoiQuyMethodButtonGroup);
            app.exponentialOptionButton.Text = 'Hàm mũ';
            app.logaritOptionButton = uiradiobutton(app.hoiQuyMethodButtonGroup);
            app.logaritOptionButton.Text = 'Logarit';
            app.NhpdliuxEditField_3Label_2 = uilabel(app.HiquyTab);
            app.NhpdliuxEditField_3Label_2.Text = 'Nhập dữ liệu x';
            app.hoiQuyDuLieuYField = uieditfield(app.HiquyTab, 'text');
            app.NhpdliuyEditField_3Label_2 = uilabel(app.HiquyTab);
            app.NhpdliuyEditField_3Label_2.Text = 'Nhập dữ liệu y';
            app.hoiQuyDuLieuXField = uieditfield(app.HiquyTab, 'text');
            app.NhpgitrcndonEditFieldLabel = uilabel(app.HiquyTab);
            app.NhpgitrcndonEditFieldLabel.Text = 'Nhập giá trị cần dự đoán';
            app.hoiQuyPredictorEditField = uieditfield(app.HiquyTab, 'numeric');
            app.hoiQuyPredictionButton = uibutton(app.HiquyTab, 'push');
            app.hoiQuyPredictionButton.ButtonPushedFcn = createCallbackFcn(app, @hoiQuyPredictionButtonPushed, true);
            app.hoiQuyPredictionButton.Text = 'Tính';
            app.hoiQuyPredictionResultTitle = uilabel(app.HiquyTab);
            app.hoiQuyPredictionResultTitle.Text = 'Kết quả dự đoán: y = ';
            app.hoiQuyPredictionResultLabel = uilabel(app.HiquyTab);
            app.hoiQuyPredictionResultLabel.Text = '';
            app.hoiQuyDrawerButton = uibutton(app.HiquyTab, 'push');
            app.hoiQuyDrawerButton.ButtonPushedFcn = createCallbackFcn(app, @hoiQuyDrawerButtonPushed, true);
            app.hoiQuyDrawerButton.Text = 'Tìm phương trình';
            app.phuongTrinhHoiQuyTitle = uilabel(app.HiquyTab);
            app.phuongTrinhHoiQuyTitle.Text = 'Kết quả phương trình hồi quy';
            app.phuongTrinhHoiQuyLabel = uilabel(app.HiquyTab);
            app.phuongTrinhHoiQuyLabel.Text = '';
            app.hoiQuyErrorMessage = uilabel(app.HiquyTab);
            app.hoiQuyErrorMessage.Text = '';
            app.ohmTab = uitab(app.TabGroup);
            app.ohmTab.Title = 'Đạo hàm';
            app.ButtonGroup_2 = uibuttongroup(app.ohmTab);
            app.HocLabel_3 = uilabel(app.ButtonGroup_2);
            app.HocLabel_3.Text = 'Hoặc';
            app.NhpccgitrxcchnhaubikhongtrngLabel_2 = uilabel(app.ButtonGroup_2);
            app.NhpccgitrxcchnhaubikhongtrngLabel_2.Text = 'Nhập các giá trị x (cách nhau bởi khoảng trắng) :';
            app.xArray_2 = uieditfield(app.ButtonGroup_2, 'text');
            app.NhpccgitrycchnhaubikhongtrngLabel_2 = uilabel(app.ButtonGroup_2);
            app.NhpccgitrycchnhaubikhongtrngLabel_2.Text = 'Nhập các giá trị y (cách nhau bởi khoảng trắng) :';
            app.yArray_2 = uieditfield(app.ButtonGroup_2, 'text');
            app.NhphmsEditFieldLabel_3 = uilabel(app.ButtonGroup_2);
            app.NhphmsEditFieldLabel_3.Text = 'Nhập hàm số';
            app.expressionInput_2 = uieditfield(app.ButtonGroup_2, 'text');
            app.NhpbchEditFieldLabel_2 = uilabel(app.ButtonGroup_2);
            app.NhpbchEditFieldLabel_2.Text = 'Nhập bước h';
            app.hInput_2 = uieditfield(app.ButtonGroup_2, 'numeric');
            app.ChnbcsaisDropDownLabel_2 = uilabel(app.ButtonGroup_2);
            app.ChnbcsaisDropDownLabel_2.Text = 'Chọn bậc sai số';
            app.errorOh_2 = uidropdown(app.ButtonGroup_2);
            app.errorOh_2.Items = {'O(h)', 'O(h^2)'};
            app.errorOh_2.Value = 'O(h)';
            app.Computexy_2 = uibutton(app.ButtonGroup_2, 'push');
            app.Computexy_2.ButtonPushedFcn = createCallbackFcn(app, @Computexy_2ButtonPushed, true);
            app.Computexy_2.Text = 'Tính toán';
            app.Computef_2 = uibutton(app.ButtonGroup_2, 'push');
            app.Computef_2.ButtonPushedFcn = createCallbackFcn(app, @Computef_2ButtonPushed, true);
            app.Computef_2.Text = 'Tính toán';
            app.ErrorsHandle_2 = uieditfield(app.ButtonGroup_2, 'text');
            app.ErrorsHandle_2.Editable = 'off';
            app.ErrorsHandle_2.Visible = 'off';
            app.derivativeErrHandle_2 = uieditfield(app.ohmTab, 'text');
            app.derivativeErrHandle_2.Visible = 'off';
            app.ChnphngphptnhohmListBoxLabel_2 = uilabel(app.ohmTab);
            app.ChnphngphptnhohmListBoxLabel_2.Text = 'Chọn phương pháp tính đạo hàm';
            app.methodCalc = uilistbox(app.ohmTab);
            app.methodCalc.Items = {'xấp xỉ tiến', 'xấp xỉ lùi', 'trung tâm'};
            app.methodCalc.Value = 'xấp xỉ tiến';
            app.NhpgitrcntnhohmEditFieldLabel_2 = uilabel(app.ohmTab);
            app.NhpgitrcntnhohmEditFieldLabel_2.Text = 'Nhập giá trị cần tính đạo hàm';
            app.derivativeValue = uieditfield(app.ohmTab, 'numeric');
            app.KtquEditFieldLabel_3 = uilabel(app.ohmTab);
            app.KtquEditFieldLabel_3.Text = 'Kết quả';
            app.derivativeResult = uieditfield(app.ohmTab, 'numeric');
            app.TchphnTab = uitab(app.TabGroup);
            app.TchphnTab.Title = 'Tích phân';
            app.NhpdliuxEditField_3Label = uilabel(app.TchphnTab);
            app.NhpdliuxEditField_3Label.Text = 'Nhập dữ liệu x';
            app.dulieuXField = uieditfield(app.TchphnTab, 'text');
            app.NhpdliuyEditField_3Label = uilabel(app.TchphnTab);
            app.NhpdliuyEditField_3Label.Text = 'Nhập dữ liệu y';
            app.dulieuYField = uieditfield(app.TchphnTab, 'text');
            app.HocLabel = uilabel(app.TchphnTab);
            app.HocLabel.Text = 'Hoặc';
            app.NhphmsEditFieldLabel = uilabel(app.TchphnTab);
            app.NhphmsEditFieldLabel.Text = 'Nhập hàm số';
            app.hamTichPhanField = uieditfield(app.TchphnTab, 'text');
            app.tichPhanOptionsButtonGroup = uibuttongroup(app.TchphnTab);
            app.tichPhanOptionsButtonGroup.Title = 'Chon phương pháp tính Tích phân';
            app.HnhthangButton = uiradiobutton(app.tichPhanOptionsButtonGroup);
            app.HnhthangButton.Text = 'Hình thang';
            app.HnhthangButton.Value = true;
            app.Simpson13Button = uiradiobutton(app.tichPhanOptionsButtonGroup);
            app.Simpson13Button.Text = 'Simpson 1/3';
            app.Simpson38Button = uiradiobutton(app.tichPhanOptionsButtonGroup);
            app.Simpson38Button.Text = 'Simpson 3/8';
            app.NhpNEditFieldLabel = uilabel(app.TchphnTab);
            app.NhpNEditFieldLabel.Text = 'Nhập N';
            app.nField = uieditfield(app.TchphnTab, 'numeric');
            app.KtquEditFieldLabel = uilabel(app.TchphnTab);
            app.KtquEditFieldLabel.Text = 'Kết quả';
            app.resultTichPhanField = uieditfield(app.TchphnTab, 'numeric');
            app.solveTichPhanButton = uibutton(app.TchphnTab, 'push');
            app.solveTichPhanButton.ButtonPushedFcn = createCallbackFcn(app, @solveTichPhanButtonPushed, true);
            app.solveTichPhanButton.Text = 'Giải';
            app.NhpcntnhTchphnLabel = uilabel(app.TchphnTab);
            app.NhpcntnhTchphnLabel.Text = 'Nhập cận để tính Tích phân';
            app.aEditFieldLabel = uilabel(app.TchphnTab);
            app.aEditFieldLabel.Text = 'a';
            app.canTichPhanAField = uieditfield(app.TchphnTab, 'numeric');
            app.bEditFieldLabel = uilabel(app.TchphnTab);
            app.bEditFieldLabel.Text = 'b';
            app.canTichPhanBField = uieditfield(app.TchphnTab, 'numeric');
            app.tichphanErrorLabel = uilabel(app.TchphnTab);
            app.tichphanErrorLabel.Text = '';
            app.GiithiunhmTab = uitab(app.TabGroup);
            app.GiithiunhmTab.Title = 'Giới thiệu nhóm';
            app.derivativeErrHandle = uieditfield(app.UIFigure, 'text');
            app.derivativeErrHandle.Visible = 'off';
        end
    end

    methods (Access = public)

        % Construct app
        function app = app

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end